classdef app_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        DropDown        matlab.ui.control.DropDown
        ContourButton   matlab.ui.control.Button
        SurfButton      matlab.ui.control.Button
        MeshButton      matlab.ui.control.Button
        PlotTypesPanel  matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        peaksData
        membraneData
        sincData
        currentData % 对于几个数据进行属性定义
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 初始化数据
            app.peaksData = peaks(35);
            app.membraneData = membrane;
            [x,y] = meshgrid(-8:.5:8);
            r = sqrt(x.^2 + y.^2) + eps;
            app.sincData = sin(r) ./ r;
        
            app.currentData = app.peaksData;
        
            % 在指定的UIAxes上绘制当前数据
            surf(app.UIAxes, app.currentData);
        end

        % Button pushed function: MeshButton
        function MeshButtonPushed(app, event)
            mesh (app.UIAxes, app.currentData);
        end

        % Button pushed function: SurfButton
        function SurfButtonPushed(app, event)
            surf (app.UIAxes, app.currentData);
        end

        % Button pushed function: ContourButton
        function ContourButtonPushed(app, event)
            contour (app.UIAxes, app.currentData);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            switch app.DropDown.Value
                case 'peaks'
                    app.currentData = app.peaksData;
                    surf(app.UIAxes, app.currentData);
                case 'membrane'
                    app.currentData = app.membraneData;
                    surf(app.UIAxes, app.currentData);
                case 'sinc'
                    app.currentData = app.sincData;
                    surf(app.UIAxes, app.currentData);
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotTypesPanel = uipanel(app.UIFigure);
            app.PlotTypesPanel.Title = 'Plot Types';
            app.MeshButton = uibutton(app.UIFigure, 'push');
            app.MeshButton.ButtonPushedFcn = createCallbackFcn(app, @MeshButtonPushed, true);
            app.MeshButton.Text = 'Mesh';
            app.SurfButton = uibutton(app.UIFigure, 'push');
            app.SurfButton.ButtonPushedFcn = createCallbackFcn(app, @SurfButtonPushed, true);
            app.SurfButton.Text = 'Surf';
            app.ContourButton = uibutton(app.UIFigure, 'push');
            app.ContourButton.ButtonPushedFcn = createCallbackFcn(app, @ContourButtonPushed, true);
            app.ContourButton.Text = 'Contour';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'peaks', 'membrane', 'sinc'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'peaks';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end