classdef Ad_s_h_cal_chart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        test_txt           matlab.ui.control.TextArea
        Draw_Button        matlab.ui.control.Button
        h_end              matlab.ui.control.NumericEditField
        hEditField_2Label  matlab.ui.control.Label
        h_start            matlab.ui.control.NumericEditField
        hEditFieldLabel    matlab.ui.control.Label
        s_end              matlab.ui.control.NumericEditField
        sLabel_2           matlab.ui.control.Label
        s_start            matlab.ui.control.NumericEditField
        sLabel             matlab.ui.control.Label
        Reset_Button       matlab.ui.control.Button
        Title              matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        T_range
        P_range
        s_cal_tp
        h_cal_tp
        x
        T_s
        P_s
        s_cal_sg
        s_cal_sl
        h_cal_sg
        h_cal_sl
        s_cal_ox
        h_cal_ox
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.T_range = 273.15:50:1073.15;
            app.P_range = [0.01, 0.002, 0.005, 0.01, 0.02, 0.05, 0.1, 0.2, 0.5, 1, 2, 5, 10, 20, 50, 80, 100];

            [ P, T ] = meshgrid(app.P_range, app.T_range);
            [app.s_cal_tp, app.h_cal_tp] = arrayfun(@cal_s_h, P, T);

            % 绘制等温线图像
            hold (app.UIAxes, 'on');
            for ii = 1:length(app.T_range)
                plot(app.UIAxes, app.s_cal_tp(ii,:), app.h_cal_tp(ii,:), 'color', 'b', 'LineWidth', 1.5);
                text(app.UIAxes, app.s_cal_tp(ii,1)+0.77, app.h_cal_tp(ii,1), sprintf('%.2f', app.T_range(ii)), 'FontSize', 8, 'Color', 'b', 'HorizontalAlignment', 'left');
            end
            
            
            % 绘制等压线图像
            for jj = 1:length(app.P_range)
                plot(app.UIAxes, app.s_cal_tp(:,jj), app.h_cal_tp(:,jj), 'color', 'r');
                text(app.UIAxes, app.s_cal_tp(end,jj), app.h_cal_tp(end,jj), sprintf('%.2f', app.P_range(jj)), 'FontSize', 8, 'Color', 'r', 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom');
            end
            
            % 绘制干度图像
            app.x = 0:0.1:1; % 干度的取值范围
            app.T_s = 273.15:3.5:623.15; % 饱和温度
            app.P_s = p_sat(app.T_s); % 饱和压力
            [app.s_cal_sl, app.h_cal_sl] = arrayfun(@(T, P) cal_s_h(P + 1e-10, T), app.T_s, app.P_s);
            [app.s_cal_sg, app.h_cal_sg] = arrayfun(@(T, P) cal_s_h(P - 1e-10, T), app.T_s, app.P_s);
            
            for kk = 1:length(app.x)
                app.h_cal_ox = app.h_cal_sl + app.x(kk) .* (app.h_cal_sg - app.h_cal_sl);
                app.s_cal_ox = app.s_cal_sl + app.x(kk) .* (app.s_cal_sg - app.s_cal_sl);
                plot(app.UIAxes, app.s_cal_ox(2:end), app.h_cal_ox(2:end), 'Color', 'g', 'LineWidth', 1.5);
                text(app.UIAxes, (app.s_cal_ox(20)+1) * 1.25 - 0.4, app.h_cal_ox(20) - 200 , sprintf('x=%.0f%%', app.x(kk)*100), 'FontSize', 8, 'Color', 'k', 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom');
            end
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: Reset_Button
        function Reset_ButtonPushed(app, event)
            % 重置轴的范围
            app.UIAxes.XLim = [0 12];
            app.UIAxes.YLim = [0 4500];
            app.s_start.Value = 0;
            app.s_end.Value = 0;
            app.h_start.Value = 0;
            app.h_end.Value = 0;
            % 重新绘制图像
            cla(app.UIAxes);
            % 绘制等温线图像
            hold (app.UIAxes, 'on');
            for ii = 1:length(app.T_range)
                plot(app.UIAxes, app.s_cal_tp(ii,:), app.h_cal_tp(ii,:), 'color', 'b', 'LineWidth', 1.5);
                text(app.UIAxes, app.s_cal_tp(ii,1)+0.77, app.h_cal_tp(ii,1), sprintf('%.2f', app.T_range(ii)), 'FontSize', 8, 'Color', 'b', 'HorizontalAlignment', 'left');
            end
            
            
            % 绘制等压线图像
            for jj = 1:length(app.P_range)
                plot(app.UIAxes, app.s_cal_tp(:,jj), app.h_cal_tp(:,jj), 'color', 'r');
                text(app.UIAxes, app.s_cal_tp(end,jj), app.h_cal_tp(end,jj), sprintf('%.2f', app.P_range(jj)), 'FontSize', 8, 'Color', 'r', 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom');
            end
            
            % 绘制干度图像
            app.x = 0:0.1:1; % 干度的取值范围
            app.T_s = 273.15:3.5:623.15; % 饱和温度
            app.P_s = p_sat(app.T_s); % 饱和压力
            [app.s_cal_sl, app.h_cal_sl] = arrayfun(@(T, P) cal_s_h(P + 1e-10, T), app.T_s, app.P_s);
            [app.s_cal_sg, app.h_cal_sg] = arrayfun(@(T, P) cal_s_h(P - 1e-10, T), app.T_s, app.P_s);
            
            for kk = 1:length(app.x)
                app.h_cal_ox = app.h_cal_sl + app.x(kk) .* (app.h_cal_sg - app.h_cal_sl);
                app.s_cal_ox = app.s_cal_sl + app.x(kk) .* (app.s_cal_sg - app.s_cal_sl);
                plot(app.UIAxes, app.s_cal_ox(2:end), app.h_cal_ox(2:end), 'Color', 'g', 'LineWidth', 1.5);
                text(app.UIAxes, (app.s_cal_ox(20)+1) * 1.25 - 0.4, app.h_cal_ox(20) - 200 , sprintf('x=%.0f%%', app.x(kk)*100), 'FontSize', 8, 'Color', 'k', 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom');
            end
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: Draw_Button
        function Draw_ButtonPushed(app, event)
            % 对于部分内容进行放大
            if app.s_start.Value == 0 && app.s_end.Value == 0 || app.h_start.Value == 0 && app.h_end.Value == 0
                app.test_txt.Value = '不能绘制范围为0的图像!';
                app.test_txt.FontColor = 'red'; % 更改文本颜色为红色
            else
                % 查找当前坐标轴上的所有文本对象
                textObjects = findobj(app.UIAxes, 'Type', 'text');
                % 删除这些文本对象
                delete(textObjects);
                % 设定当前坐标轴范围
                app.UIAxes.XLim = [app.s_start.Value app.s_end.Value];
                app.UIAxes.YLim = [app.h_start.Value app.h_end.Value];
                % 重新绘制曲线和标注
                hold(app.UIAxes, 'on');

                % 绘制等温线图像并添加标注
                for ii = 1:length(app.T_range)
                    plot(app.UIAxes, app.s_cal_tp(ii,:), app.h_cal_tp(ii,:), 'color', 'b', 'LineWidth', 1.5);
                    % 查找曲线在当前显示范围内的第一个点，等压线和干度值同理
                    idx = find(app.s_cal_tp(ii,:) >= app.s_start.Value & app.s_cal_tp(ii,:) <= app.s_end.Value & ...
                        app.h_cal_tp(ii,:) >= app.h_start.Value & app.h_cal_tp(ii,:) <= app.h_end.Value, 1);
                    if ~isempty(idx)
                        % 在第一个符合条件的点处添加标签，等压线和干度值同理
                        text(app.UIAxes, app.s_cal_tp(ii, idx), app.h_cal_tp(ii, idx), sprintf('%.2f', app.T_range(ii)), ...
                            'FontSize', 12, 'Color', 'b', 'HorizontalAlignment', 'left');
                    end
                end

                % 绘制等压线图像并添加标注
                for jj = 1:length(app.P_range)
                    plot(app.UIAxes, app.s_cal_tp(:, jj), app.h_cal_tp(:, jj), 'color', 'r');
                    idx = find(app.s_cal_tp(:, jj) >= app.s_start.Value & app.s_cal_tp(:, jj) <= app.s_end.Value & ...
                        app.h_cal_tp(:, jj) >= app.h_start.Value & app.h_cal_tp(:, jj) <= app.h_end.Value, 1);
                    if ~isempty(idx)
                        text(app.UIAxes, app.s_cal_tp(idx, jj), app.h_cal_tp(idx, jj), sprintf('%.2f', app.P_range(jj)), ...
                            'FontSize', 12, 'Color', 'r', 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom');
                    end
                end

                % 绘制干度图像并添加标注
                for kk = 1:length(app.x)
                    app.h_cal_ox = app.h_cal_sl + app.x(kk) .* (app.h_cal_sg - app.h_cal_sl);
                    app.s_cal_ox = app.s_cal_sl + app.x(kk) .* (app.s_cal_sg - app.s_cal_sl);
                    plot(app.UIAxes, app.s_cal_ox(2:end), app.h_cal_ox(2:end), 'Color', 'g', 'LineWidth', 1.5);
                    idx = find(app.s_cal_ox(2:end) >= app.s_start.Value & app.s_cal_ox(2:end) <= app.s_end.Value & ...
                        app.h_cal_ox(2:end) >= app.h_start.Value & app.h_cal_ox(2:end) <= app.h_end.Value, 1);
                    if ~isempty(idx)
                        text(app.UIAxes, app.s_cal_ox(idx + 1), app.h_cal_ox(idx + 1), sprintf('x=%.0f%%', app.x(kk) * 100), ...
                            'FontSize', 12, 'Color', 'k', 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom');
                    end
                end

                hold(app.UIAxes, 'off');
                % % 这是采用了索引方法进行的坐标跟随（没能完美实现）
                % % 对于熵的坐标跟随标签
                % Logic_s_Index = app.s_cal_tp <= app.s_end.Value & app.s_cal_tp >= app.s_start.Value; % 创建一个逻辑数组，表示小于N的元素
                % fit_value = app.s_cal_tp(Logic_s_Index);
                % tp_max_value = max(fit_value);
                % tp_min_value = min(fit_value);
                % [~, tp_max_Index] = find(app.s_cal_tp == tp_max_value, 1, 'first');
                % [~, tp_min_Index] = find(app.s_cal_tp == tp_min_value, 1, 'first');
                % % app.test_txt.Value = num2str(tp_max_Index);
                % hold (app.UIAxes, 'on');
                % for ii = tp_max_Index:tp_min_Index
                %     text(app.UIAxes, app.s_cal_tp(ii, 1), app.h_cal_tp(ii, 1), sprintf('%.2fk', app.T_range(ii)), 'FontSize', 12, 'Color', 'b', 'HorizontalAlignment', 'right');
                % end
                % % 焓的坐标跟随标签
                % % 创建一个逻辑数组，表示在特定焓值范围内的元素
                % Logic_h_Index = app.h_cal_tp <= app.h_end.Value & app.h_cal_tp >= app.h_start.Value;
                % % 从符合条件的焓值中找到最大值和最小值
                % fit_value = app.h_cal_tp(Logic_h_Index);
                % h_max_value = max(fit_value);
                % h_min_value = min(fit_value);
                % % 查找最大值和最小值的索引
                % [~, h_max_Index] = find(app.h_cal_tp == h_max_value, 1, 'first');
                % [~, h_min_Index] = find(app.h_cal_tp == h_min_value, 1, 'first');
                % % 在图上标注压强P
                % hold (app.UIAxes, 'on');
                % for ii = h_max_Index:h_min_Index
                %     % 假设每个焓值对应一个压强值，这里需要根据实际情况调整
                %     % 这里简化处理，直接使用P_range(ii)作为示例，实际应根据h_cal_ot和P的关系确定
                %     text(app.UIAxes, app.s_cal_tp(ii, ii), app.h_cal_tp(ii, ii), sprintf('%.2fMPa', app.P_range(ii)), 'FontSize', 12, 'Color', 'r', 'HorizontalAlignment', 'right');
                % end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot of s-h')
            xlabel(app.UIAxes, '熵 s [KJ/(kg・K)]')
            ylabel(app.UIAxes, '焓 h [KJ/kg]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 12];
            app.UIAxes.YLim = [0 4500];
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = '焓熵图';
            app.Reset_Button = uibutton(app.UIFigure, 'push');
            app.Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @Reset_ButtonPushed, true);
            app.Reset_Button.Text = '重置';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '熵（s）开始点';
            app.s_start = uieditfield(app.UIFigure, 'numeric');
            app.s_start.Value = 8;
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = '熵（s）终止点';
            app.s_end = uieditfield(app.UIFigure, 'numeric');
            app.s_end.Value = 10;
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Text = '焓（h）开始点';
            app.h_start = uieditfield(app.UIFigure, 'numeric');
            app.h_start.Value = 2000;
            app.hEditField_2Label = uilabel(app.UIFigure);
            app.hEditField_2Label.Text = '焓（h）结束点';
            app.h_end = uieditfield(app.UIFigure, 'numeric');
            app.h_end.Value = 3500;
            app.Draw_Button = uibutton(app.UIFigure, 'push');
            app.Draw_Button.ButtonPushedFcn = createCallbackFcn(app, @Draw_ButtonPushed, true);
            app.Draw_Button.Text = '开始';
            app.test_txt = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ad_s_h_cal_chart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end